classdef Array_Control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_10                     matlab.ui.control.Label
        BioinspiredScienceTechnologyResearchLabel  matlab.ui.control.Label
        UBDVTLaboratoryLabel         matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        ControlPageTab               matlab.ui.container.Tab
        SystemInitializedLamp        matlab.ui.control.Lamp
        SystemInitializedLampLabel   matlab.ui.control.Label
        CollectingDataLampLabel      matlab.ui.control.Label
        CollectingDataLamp           matlab.ui.control.Lamp
        ArrayControlPannelLabel      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        AcousticsConfigurationTab    matlab.ui.container.Tab
        AcousticsControlPannelLabel  matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        CameraConfigurationTab       matlab.ui.container.Tab
        CameraControlPannelLabel     matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    properties (Access = public)
        % --DAQ Devices
            NI_daq;             % daq device obj
            sample_rate;        % Sample rate of daq in Hz
            s1 = "Dev1";        % Slots 
            s2 = "PXI1Slot3";
            s3 = "PXI1slot4";

        % --Initialize Microphone channels
            % --Initialize Terminal 1
                mic_01; mic_02; mic_03; mic_04; mic_05; mic_06; mic_07; 
                mic_08;
                
            % --Initialize Terminal 2
                mic_09; mic_10; mic_11; mic_12; mic_13; mic_14; mic_15; 
                mic_16;
    
            % --Initialize Terminal 3
                mic_17; mic_18; mic_19; mic_20; mic_21; mic_22; mic_23; 
                mic_24;
    
            % --Initialize Terminal 4
                mic_25; mic_26; mic_27; mic_28; mic_29; mic_30; mic_31; 
                mic_32;

        % --Initialize Camera Output channels
            trigger_signal;
            trigger_channel;
            sync_signal;
            sync_channel;
            delay_time;
            
        % --Test control variables
            is_running;         % test is running bool
            time_start;         % Time obj for when test starts
            time_end;           % Time obj for when test ends
            test_duration;      % Test duration
            test_data;

        % --Save data
            data_title;
            mat_title;
            BG_test_data;
            test_data_matrix;

        % --Sound Output
            sound_value;        % Sound file drop-down selection index
            playSound;          % Play sound bool
            sound_sample;       % Sound sample rate
            audio;
                chirp_sound;
                chirp_fs;
                tone_sound;
                tone_fs;
                DTMF_sound;
                DTMF_fs;
                keys_sound;
                keys_fs;
                bat_1_sound;
                bat_1_fs;
    
        % --Calling helper app
            visualizeData; 
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Methods %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = public)

        function playSound = play_sound(~, audio, fs, playSound)
            %{
                Play sound if selected or will end sound if selected and
                sound is already being played. This will be able to play
                any input sound.

                Sam Kramer
                June 9th, 2023
            %}
    
            if playSound

                % --Play sound if play_sound is tru
                    sound(audio,fs)

                % --Reset toggle button if done playing sound
                    playSound = "false";

            elseif ~playSound

                % --Play sound if not true
                    clear sound

            end
        end 

        function BG_noise_data = background_noise_test(app)
        %{
            This will take a quick 5 second test that will be used for determining
            the background noise within the room before a test is taken. Defaults
            to 250,000 Hz sample rate for the test.
        
            Sam Kramer
            June 12th, 2023
        
            See also add_daq_channels, and save_daq_data
        %}
        
        % --Function Body
            try
                % --Setup Daq and channels
                    app.NI_daq.Rate = 250000;
            
                % --Start Test
                    app.CollectingDataLamp.Color = [0 1 0];
                    bg_test_start = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
                    
                    start(app.NI_daq,"Duration",seconds(5))
                        pause(5)
                    stop(app.NI_daq)
                    
                    bg_test_end = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
                    app.CollectingDataLamp.Color = [1 0 0];
    
                % --Read data
                    bg_test_data = read(app.NI_daq,"all");
            
                % --Save Data as .csv file
                    BG_noise_data = save_daq_data(bg_test_data,bg_test_start,bg_test_end,'Background_Noise_Test');
    
            catch
    
            end
        end

        function reformat_data(app)
            %{
                This function will be used to reformat the data that is
                taken by the tests. This will take in the time table of the
                data and then will output a formatted matrix without the
                start and end time values.

                Sam Kramer
                June 26th, 2023
            %}

            % --Pull data out of timetable function
                time = app.test_data.Time;
                time = seconds(time);

                mic1 = app.test_data.Dev1_ai0;
                mic2 = app.test_data.Dev1_ai1;
                mic3 = app.test_data.Dev1_ai2;
                mic4 = app.test_data.Dev1_ai3;
                mic5 = app.test_data.Dev1_ai4;
                mic6 = app.test_data.Dev1_ai5;
                mic7 = app.test_data.Dev1_ai6;
                mic8 = app.test_data.Dev1_ai7;
                
                mic9 = app.test_data.PXI1Slot3_ai0;
                mic10 = app.test_data.PXI1Slot3_ai1;
                mic11 = app.test_data.PXI1Slot3_ai2;
                mic12 = app.test_data.PXI1Slot3_ai3;
                mic13 = app.test_data.PXI1Slot3_ai4;
                mic14 = app.test_data.PXI1Slot3_ai5;
                mic15 = app.test_data.PXI1Slot3_ai6;
                mic16 = app.test_data.PXI1Slot3_ai7;

                mic17 = app.test_data.PXI1Slot4_ai0;
                mic18 = app.test_data.PXI1Slot4_ai1;
                mic19 = app.test_data.PXI1Slot4_ai2;
                mic20 = app.test_data.PXI1Slot4_ai3;
                mic21 = app.test_data.PXI1Slot4_ai4;
                mic22 = app.test_data.PXI1Slot4_ai5;
                mic23 = app.test_data.PXI1Slot4_ai6;
                mic24 = app.test_data.PXI1Slot4_ai7;
                mic25 = app.test_data.PXI1Slot4_ai8;
                mic26 = app.test_data.PXI1Slot4_ai9;
                mic27 = app.test_data.PXI1Slot4_ai10;
                mic28 = app.test_data.PXI1Slot4_ai11;
                mic29 = app.test_data.PXI1Slot4_ai12;
                mic30 = app.test_data.PXI1Slot4_ai13;
                mic31 = app.test_data.PXI1Slot4_ai14;
                mic32 = app.test_data.PXI1Slot4_ai15;

            % --Place into a final matrix
                app.test_data_matrix = [time, mic1, mic2, mic3, mic4 , mic5, mic6, mic7, mic8, ...
                                        mic9, mic10, mic11, mic12, mic13, mic14, mic15, mic16, ...
                                        mic17, mic18, mic19, mic20, mic21, mic22, mic23, mic24, ...
                                        mic25, mic26, mic27, mic28, mic29, mic30, mic31, mic32];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
                Startup function for the app. This handles all the startup
                and setup for the app. All mic channels opened in this
                function. The try statement is to prevent the app from
                crashing if there is no daq connected.
            
                Sam Kramer
                June 7th, 2023
            %}
            
            try     % Try connecting to daq

                % --Connecting to daq
                    app.NI_daq = daq("ni");
                    app.NI_daq.Rate = 10000;      % Set the daq sample rate
                    
                % --Adding channel inputs
                    % --Add inputs for terminal 1
                        app.mic_01 = addinput(app.NI_daq, app.s1,"ai0","Voltage");
                        app.mic_02 = addinput(app.NI_daq, app.s1,"ai1","Voltage");
                        app.mic_03 = addinput(app.NI_daq, app.s1,"ai2","Voltage");
                        app.mic_04 = addinput(app.NI_daq, app.s1,"ai3","Voltage");
                        app.mic_05 = addinput(app.NI_daq, app.s1,"ai4","Voltage");
                        app.mic_06 = addinput(app.NI_daq, app.s1,"ai5","Voltage");
                        app.mic_07 = addinput(app.NI_daq, app.s1,"ai6","Voltage");
                        app.mic_08 = addinput(app.NI_daq, app.s1,"ai7","Voltage");
    
                    % --Add inputs for terminal 2
                        app.mic_09 = addinput(app.NI_daq, app.s2,"ai0","Voltage");
                        app.mic_10 = addinput(app.NI_daq, app.s2,"ai1","Voltage");
                        app.mic_11 = addinput(app.NI_daq, app.s2,"ai2","Voltage");
                        app.mic_12 = addinput(app.NI_daq, app.s2,"ai3","Voltage");
                        app.mic_13 = addinput(app.NI_daq, app.s2,"ai4","Voltage");
                        app.mic_14 = addinput(app.NI_daq, app.s2,"ai5","Voltage");
                        app.mic_15 = addinput(app.NI_daq, app.s2,"ai6","Voltage");
                        app.mic_16 = addinput(app.NI_daq, app.s2,"ai7","Voltage");
    
                    % --Add inputs for terminal 3
                        app.mic_17 = addinput(app.NI_daq, app.s3,"ai0","Voltage");
                        app.mic_18 = addinput(app.NI_daq, app.s3,"ai1","Voltage");
                        app.mic_19 = addinput(app.NI_daq, app.s3,"ai2","Voltage");
                        app.mic_20 = addinput(app.NI_daq, app.s3,"ai3","Voltage");
                        app.mic_21 = addinput(app.NI_daq, app.s3,"ai4","Voltage");
                        app.mic_22 = addinput(app.NI_daq, app.s3,"ai5","Voltage");
                        app.mic_23 = addinput(app.NI_daq, app.s3,"ai6","Voltage");
                        app.mic_24 = addinput(app.NI_daq, app.s3,"ai7","Voltage");
    
                    % --Add inputs for terminal 4
                        app.mic_25 = addinput(app.NI_daq, app.s3,"ai8","Voltage");
                        app.mic_26 = addinput(app.NI_daq, app.s3,"ai9","Voltage");
                        app.mic_27 = addinput(app.NI_daq, app.s3,"ai10","Voltage");
                        app.mic_28 = addinput(app.NI_daq, app.s3,"ai11","Voltage");
                        app.mic_29 = addinput(app.NI_daq, app.s3,"ai12","Voltage");
                        app.mic_30 = addinput(app.NI_daq, app.s3,"ai13","Voltage");
                        app.mic_31 = addinput(app.NI_daq, app.s3,"ai14","Voltage");
                        app.mic_32 = addinput(app.NI_daq, app.s3,"ai15","Voltage");

                    % --Add Output channels for terminal 1
                        app.trigger_channel = addoutput(app.NI_daq, app.s1,"ai1","Voltage");

            catch       % if there is an error, return

            end

            % --Test setup
                app.is_running = false;
                app.test_duration = 2;
                app.CollectingDataLamp.Color = [1 0 0];

            % --Save data as .mat file
                date_test = datetime("now");
                dayday = string(day(date_test));
                monthmonth = string(month(date_test));
                yearyear = string(year(date_test));
                app.mat_title = "Test_Data_" + monthmonth + "_" + dayday + "_" + yearyear; 
                app.matfilenameEditField.Value = app.mat_title;
                app.mat_title = app.mat_title + ".mat";

            % --Sound Output
                app.sound_value = "Tone";
                app.playSound = false;

                [app.chirp_sound, app.chirp_fs] = audioread("Chirp 440 Hz.wav");
                [app.tone_sound, app.tone_fs] = audioread("Tone 440 Hz.mp3");
                [app.DTMF_sound, app.DTMF_fs] = audioread("DTMF Tones.wav");
                [app.keys_sound, app.keys_fs] = audioread("Shaking Keys.mp3");
                [app.bat_1_sound, app.bat_1_fs] = audioread("Nyctalus_leisleri_3_o.wav");

            % --Set basic audio output to chirp sound
                app.audio = app.chirp_sound;
                app.sound_sample = app.chirp_fs;

            % --Disable visualize data
                app.VisualizeDataButton.Enable = 'off';

            % --Indicate Start-up function is complete
                app.SystemInitializedLamp.Color = [0 1 0];
                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %{
                Properly cancels all actions the app is doing and will
                close app. Called when user presses the "x" button to close
                app.

                Sam Kramer
                June 8th, 2023
            %}

            % --Close app
                delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlPageTab = uitab(app.TabGroup);
            app.ControlPageTab.Title = 'Control Page';
            app.Label_7 = uilabel(app.ControlPageTab);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.ControlPageTab);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.ControlPageTab);
            app.Label_9.Text = '';
            app.ArrayControlPannelLabel = uilabel(app.ControlPageTab);
            app.ArrayControlPannelLabel.Text = 'Array Control Pannel';
            app.CollectingDataLamp = uilamp(app.ControlPageTab);
            app.CollectingDataLamp.Tag = 'lamp';
            app.CollectingDataLampLabel = uilabel(app.ControlPageTab);
            app.CollectingDataLampLabel.Text = 'Collecting Data';
            app.SystemInitializedLampLabel = uilabel(app.ControlPageTab);
            app.SystemInitializedLampLabel.Text = 'System Initialized';
            app.SystemInitializedLamp = uilamp(app.ControlPageTab);
            app.AcousticsConfigurationTab = uitab(app.TabGroup);
            app.AcousticsConfigurationTab.Title = 'Acoustics Configuration';
            app.Label_4 = uilabel(app.AcousticsConfigurationTab);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.AcousticsConfigurationTab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.AcousticsConfigurationTab);
            app.Label_6.Text = '';
            app.AcousticsControlPannelLabel = uilabel(app.AcousticsConfigurationTab);
            app.AcousticsControlPannelLabel.Text = 'Acoustics Control Pannel';
            app.CameraConfigurationTab = uitab(app.TabGroup);
            app.CameraConfigurationTab.Title = 'Camera Configuration';
            app.Label = uilabel(app.CameraConfigurationTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.CameraConfigurationTab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.CameraConfigurationTab);
            app.Label_3.Text = '';
            app.CameraControlPannelLabel = uilabel(app.CameraConfigurationTab);
            app.CameraControlPannelLabel.Text = 'Camera Control Pannel';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Vertical_VT_Full_Color_RGB.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UBD logo original.png');
            app.UBDVTLaboratoryLabel = uilabel(app.UIFigure);
            app.UBDVTLaboratoryLabel.Text = 'UBD - VT Laboratory';
            app.BioinspiredScienceTechnologyResearchLabel = uilabel(app.UIFigure);
            app.BioinspiredScienceTechnologyResearchLabel.Text = 'Bioinspired Science & Technology Research';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '2023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Array_Control_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end